classdef FFCSguide_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SimulateFFCSDataNowButton       matlab.ui.control.Button
        InitialiseParametersButton      matlab.ui.control.Button
        TICSforSpeedandDiffusionPanel   matlab.ui.container.Panel
        ApplyTICSforvandDButton         matlab.ui.control.Button
        FittingModelDropDown_2          matlab.ui.control.DropDown
        tau_maxtofitEditField_3         matlab.ui.control.NumericEditField
        tau_maxtofitEditField_3Label    matlab.ui.control.Label
        FittingModelDropDownLabel_2     matlab.ui.control.Label
        ICSforNPanel                    matlab.ui.container.Panel
        ShowFitButton                   matlab.ui.control.Button
        ApplyICSforrecoveryofNButton    matlab.ui.control.Button
        ExcludeG_00DropDown             matlab.ui.control.DropDown
        ExcludeG_00fromfitLabel         matlab.ui.control.Label
        FitAverageCFDropDown            matlab.ui.control.DropDown
        FitAverageCFDropDownLabel       matlab.ui.control.Label
        AnalyseImageEditField           matlab.ui.control.NumericEditField
        AnalyseImageLabel               matlab.ui.control.Label
        ChoiceofFFCSAnalysisButtonGroup  matlab.ui.container.ButtonGroup
        TICSforspeedanddiffusionButton  matlab.ui.control.RadioButton
        STICSforflowButton              matlab.ui.control.RadioButton
        ICSforNButton                   matlab.ui.control.RadioButton
        FCSButton                       matlab.ui.control.RadioButton
        STICSforFlowMeasurementPanel    matlab.ui.container.Panel
        ShowFlowPlotButton              matlab.ui.control.Button
        ApplySTICSforflowrecoveryButton  matlab.ui.control.Button
        ImmobileFilteringDropDown       matlab.ui.control.DropDown
        ImmobileFilteringDropDownLabel  matlab.ui.control.Label
        tau_maxtofitEditField_2         matlab.ui.control.NumericEditField
        tau_maxtofitEditField_2Label    matlab.ui.control.Label
        FCSAnalysisPanel                matlab.ui.container.Panel
        FittingModelDropDown            matlab.ui.control.DropDown
        FittingModelDropDownLabel       matlab.ui.control.Label
        CalculateACFforFCSdataButton    matlab.ui.control.Button
        tau_maxtofitEditField           matlab.ui.control.NumericEditField
        tau_maxtofitEditFieldLabel      matlab.ui.control.Label
        FrameSlider                     matlab.ui.control.Slider
        FrameSliderLabel                matlab.ui.control.Label
        ParticlesPopulationsSpecificationsPanel  matlab.ui.container.Panel
        EditField_7                     matlab.ui.control.EditField
        ParticleDencitypermum2EditFieldLabel_8  matlab.ui.control.Label
        EditField_6                     matlab.ui.control.EditField
        ParticleDencitypermum2EditFieldLabel_7  matlab.ui.control.Label
        EditField_5                     matlab.ui.control.EditField
        ParticleDencitypermum2EditFieldLabel_6  matlab.ui.control.Label
        EditField_4                     matlab.ui.control.EditField
        ParticleDencitypermum2EditFieldLabel_5  matlab.ui.control.Label
        EditField_3                     matlab.ui.control.EditField
        ParticleDencitypermum2EditFieldLabel_4  matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        ParticleDencitypermum2EditFieldLabel_3  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        ParticleDencitypermum2EditFieldLabel_2  matlab.ui.control.Label
        SimulationParametersPanel       matlab.ui.container.Panel
        MaskSizeToCreateCellArea0to1EditField  matlab.ui.control.NumericEditField
        MaskSizeToCreateCellArea0to1EditFieldLabel  matlab.ui.control.Label
        DoFCSExperimentDropDown         matlab.ui.control.DropDown
        DoFCSExperimentDropDownLabel    matlab.ui.control.Label
        BleachingAndBlinkingBehaviourDropDown  matlab.ui.control.DropDown
        BleachingAndBlinkingBehaviourDropDownLabel  matlab.ui.control.Label
        GaussianBackgroundNoise0to1EditField  matlab.ui.control.NumericEditField
        GaussianBackgroundNoise0to1EditFieldLabel  matlab.ui.control.Label
        DetectorCountingNoise1to20EditField  matlab.ui.control.NumericEditField
        DetectorCountingNoise1to20Label  matlab.ui.control.Label
        ImagesBitDepthDropDown          matlab.ui.control.DropDown
        ImagesBitDepthDropDownLabel     matlab.ui.control.Label
        PSFAxialRadiusmumEditField      matlab.ui.control.NumericEditField
        PSFAxialRadiusmumEditFieldLabel  matlab.ui.control.Label
        PSFLateralRadiusmumEditField    matlab.ui.control.NumericEditField
        PSFLateralRadiusmumEditFieldLabel  matlab.ui.control.Label
        PSFtypeDropDown                 matlab.ui.control.DropDown
        PSFtypeDropDownLabel            matlab.ui.control.Label
        FrameTimesEditField             matlab.ui.control.NumericEditField
        FrameTimesEditFieldLabel        matlab.ui.control.Label
        PixelSizemumEditField           matlab.ui.control.NumericEditField
        PixelsizeummLabel               matlab.ui.control.Label
        ofimagesinseriesEditField       matlab.ui.control.NumericEditField
        ofimagesinseriesEditFieldLabel  matlab.ui.control.Label
        ImagesizeinypixelsEditField     matlab.ui.control.NumericEditField
        ImagesizeinypixelsEditFieldLabel  matlab.ui.control.Label
        ImagesizeinxpixelsEditField     matlab.ui.control.NumericEditField
        ImagesizeinxpixelsEditFieldLabel  matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        sizeXdesired
        sizeYdesired
        sizeT
        pixelsize
        timesize
        PSFType
        PSFSize
        PSFZ
        noBits
        countingNoise
        backgroundNoise
        bleachType
        FCS
        masksize
        density
        bleachDecay
        qYield
        diffCoeff
        flowX
        flowY
        flowZ
        image_data
        tSlider
        choiceAnalysis
        im
        taumax
        choiceFCS
        corr
        lags
        removeG00
        analyseAve
        ICS2DCorrCrop
        a 
        sticscorr
        filtering
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitialiseParametersButton
        function InitialiseParametersButtonPushed(app, event)
            app.sizeXdesired=256;
            app.sizeYdesired=256;
            app.sizeT=100;
            app.pixelsize=0.1;
            app.timesize=1;
            app.PSFType='g';
            app.PSFSize=0.4;
            app.PSFZ=0;
            app.noBits=12;
            app.countingNoise=0;
            app.backgroundNoise=0;
            app.bleachType='none';
            app.FCS='n';
            app.masksize=0;
            app.density=1;
            app.bleachDecay=0;
            app.qYield=1;
            app.diffCoeff=0;
            app.flowX=0;
            app.flowY=0;
            app.flowZ=0;
            app.tSlider=1;
            app.FCSAnalysisPanel.Enable='on';
            app.ICSforNPanel.Enable='off';
            app.TICSforSpeedandDiffusionPanel.Enable='off';
            app.STICSforFlowMeasurementPanel.Enable='off';
            app.im=1;
            app.taumax=20;
            app.choiceFCS='1 component Diffusion';
            app.analyseAve='n';
            app.removeG00='n';
            app.im=1;
            app.sticscorr=[];
            app.filtering='no';
        end

        % Value changed function: ImagesizeinxpixelsEditField
        function ImagesizeinxpixelsEditFieldValueChanged(app, event)
            value = app.ImagesizeinxpixelsEditField.Value;
            app.sizeXdesired=value;
        end

        % Value changed function: ImagesizeinypixelsEditField
        function ImagesizeinypixelsEditFieldValueChanged(app, event)
            value = app.ImagesizeinypixelsEditField.Value;
            app.sizeYdesired=value;
        end

        % Value changed function: ofimagesinseriesEditField
        function ofimagesinseriesEditFieldValueChanged(app, event)
            value = app.ofimagesinseriesEditField.Value;
            app.sizeT=value;
        end

        % Value changed function: PixelSizemumEditField
        function PixelSizemumEditFieldValueChanged(app, event)
            value = app.PixelSizemumEditField.Value;
            app.pixelsize=value;
        end

        % Value changed function: FrameTimesEditField
        function FrameTimesEditFieldValueChanged(app, event)
            value = app.FrameTimesEditField.Value;
            app.timesize=value;
        end

        % Value changed function: PSFtypeDropDown
        function PSFtypeDropDownValueChanged(app, event)
            value = app.PSFtypeDropDown.Value;
            if strcmp(value,'Gaussian')
            app.PSFType='g';
            elseif strcmp(value,'Airy')
            app.PSFType='a';
            end
        end

        % Drop down opening function: PSFtypeDropDown
        function PSFtypeDropDownOpening(app, event)
            app.PSFType='g';
        end

        % Value changed function: PSFLateralRadiusmumEditField
        function PSFLateralRadiusmumEditFieldValueChanged(app, event)
            value = app.PSFLateralRadiusmumEditField.Value;
            app.PSFSize=value;
        end

        % Value changed function: PSFAxialRadiusmumEditField
        function PSFAxialRadiusmumEditFieldValueChanged(app, event)
            value = app.PSFAxialRadiusmumEditField.Value;
            app.PSFZ=value;
        end

        % Value changed function: ImagesBitDepthDropDown
        function ImagesBitDepthDropDownValueChanged(app, event)
            value = app.ImagesBitDepthDropDown.Value;
            app.noBits=value;
        end

        % Drop down opening function: ImagesBitDepthDropDown
        function ImagesBitDepthDropDownOpening(app, event)
            app.noBits=12;
        end

        % Value changed function: DetectorCountingNoise1to20EditField
        function DetectorCountingNoise1to20EditFieldValueChanged(app, event)
            value = app.DetectorCountingNoise1to20EditField.Value;
            app.countingNoise=value;
        end

        % Value changed function: GaussianBackgroundNoise0to1EditField
        function GaussianBackgroundNoise0to1EditFieldValueChanged(app, event)
            value = app.GaussianBackgroundNoise0to1EditField.Value;
            app.backgroundNoise=value;
        end

        % Value changed function: BleachingAndBlinkingBehaviourDropDown
        function BleachingAndBlinkingBehaviourDropDownValueChanged(app, event)
            value = app.BleachingAndBlinkingBehaviourDropDown.Value;
            app.bleachType=value;
        end

        % Drop down opening function: BleachingAndBlinkingBehaviourDropDown
        function BleachingAndBlinkingBehaviourDropDownOpening(app, event)
            app.bleachType='none';
        end

        % Value changed function: DoFCSExperimentDropDown
        function DoFCSExperimentDropDownValueChanged(app, event)
            value = app.DoFCSExperimentDropDown.Value;
            if strcmp(value,'no')
                app.FCS='n';
            elseif strcmp(value,'yes')
                app.FCS='y';
            end
        end

        % Drop down opening function: DoFCSExperimentDropDown
        function DoFCSExperimentDropDownOpening(app, event)
            app.FCS='n';
        end

        % Value changed function: MaskSizeToCreateCellArea0to1EditField
        function MaskSizeToCreateCellArea0to1EditFieldValueChanged(app, event)
            value = app.MaskSizeToCreateCellArea0to1EditField.Value;
            app.masksize=value;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.density=str2num(value);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.bleachDecay=str2num(value);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.qYield=str2num(value);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.diffCoeff=str2num(value);
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            app.flowX=str2num(value);
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            app.flowY=str2num(value);
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            value = app.EditField_7.Value;
            app.flowZ=str2num(value);
        end

        % Button pushed function: SimulateFFCSDataNowButton
        function SimulateFFCSDataNowButtonPushed(app, event)
         app.sticscorr=[];
         app.corr=[];
         app.ICS2DCorrCrop =[];
         [app.image_data] = simul8tr2GUI(app.UIAxes2,app.sizeXdesired,app.sizeYdesired,app.sizeT,app.density,app.bleachType,app.bleachDecay,app.qYield,app.pixelsize,app.timesize,app.PSFType,app.PSFSize,app.PSFZ,app.noBits,app.diffCoeff,app.flowX,app.flowY,app.flowZ,app.countingNoise,app.backgroundNoise,app.FCS,app.masksize);
         cla(app.UIAxes,'reset');
         cla(app.UIAxes3)
         xlabel(app.UIAxes,'')
         ylabel(app.UIAxes,'')
         zlabel(app.UIAxes,'')
         title(app.UIAxes,'')
         if strcmp(app.FCS,'n')
             app.FrameSlider.Value=1;
             app.FrameSlider.Limits=[1 size(app.image_data,3)];
                app.UIAxes.YDir='reverse';
                colormap(app.UIAxes,'pink')
                imagesc(app.UIAxes,app.image_data(:,:,app.tSlider))
                xlim(app.UIAxes,[1 size(app.image_data,2)])
                ylim(app.UIAxes,[1 size(app.image_data,1)])
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                set(app.UIAxes,'XScale','linear');
            elseif strcmp(app.FCS,'y')
                app.UIAxes.YDir='normal';
                colormap(app.UIAxes,'pink')
                plot(app.UIAxes,(1:length(app.image_data))*app.timesize,app.image_data)
                xlabel(app.UIAxes,'time (s)','FontSize',16)
                ylabel(app.UIAxes,'Intensity (A.U.)','FontSize',16)
                title(app.UIAxes,'Simulated FCS experiment','FontSize',20)
                set(app.UIAxes,'XTick',[1:1000:length(app.image_data)]*app.timesize,'YTick',[0:10:max(app.image_data)]);
                set(app.UIAxes,'FontSize',20)
                xlim(app.UIAxes,[app.timesize length(app.image_data)*app.timesize])
                ylim(app.UIAxes,[0 max(app.image_data)])
                set(app.UIAxes,'XScale','linear');
            end
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            changingValue = event.Value;
            app.tSlider=uint16(ceil(changingValue));
            % cla(app.UIAxes,'reset')
            % cla(app.UIAxes3)
            
            if strcmp(app.FCS,'n')
                if isempty(app.sticscorr)
                    app.UIAxes.YDir='reverse';
                    colormap(app.UIAxes,'pink')
                    imagesc(app.UIAxes,app.image_data(:,:,app.tSlider))
                    xlim(app.UIAxes,[1 size(app.image_data,2)])
                    ylim(app.UIAxes,[1 size(app.image_data,1)])
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    set(app.UIAxes,'XScale','linear');
                elseif ~isempty(app.sticscorr)
                    [X,Y] = meshgrid(-((size(app.sticscorr,2)-1)/2)*app.pixelsize:app.pixelsize:((size(app.sticscorr,2)-1)/2)*app.pixelsize,-((size(app.sticscorr,1)-1)/2)*app.pixelsize:app.pixelsize:(size(app.sticscorr,1)-1)/2*app.pixelsize);
                    app.UIAxes.YDir='reverse';
                    xlabel(app.UIAxes,'\eta (\mum)','FontSize',20)
                    ylabel(app.UIAxes,'\xi (\mum)','FontSize',20)

                    colormap(app.UIAxes,'pink')
                    %imagesc(app.UIAxes,X,Y,app.sticscorr(:,:,app.tSlider))
                    imagesc(app.UIAxes,X(1,:),Y(:,1),app.sticscorr(:,:,app.tSlider))
                    set(app.UIAxes,'XLim',[min(X(:)) max(X(:))])
                    set(app.UIAxes,'YLim',[min(Y(:))  max(Y(:))])
                    app.UIAxes.XTick = '';
                    app.UIAxes.YTick = '';
                    set(app.UIAxes,'XScale','linear');
                end
            end
        end

        % Selection changed function: ChoiceofFFCSAnalysisButtonGroup
        function ChoiceofFFCSAnalysisButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChoiceofFFCSAnalysisButtonGroup.SelectedObject;
            app.choiceAnalysis=selectedButton.Text;
            if strcmp(app.choiceAnalysis,'FCS') 
                app.FCSAnalysisPanel.Enable='on';
                app.ICSforNPanel.Enable='off';
                app.TICSforSpeedandDiffusionPanel.Enable='off';
                app.STICSforFlowMeasurementPanel.Enable='off';
            elseif strcmp(app.choiceAnalysis,'ICS for <N>')
                app.FCSAnalysisPanel.Enable='off';
                app.ICSforNPanel.Enable='on';
                app.TICSforSpeedandDiffusionPanel.Enable='off';
                app.STICSforFlowMeasurementPanel.Enable='off';
            elseif strcmp(app.choiceAnalysis,'TICS for speed and diffusion')
                app.FCSAnalysisPanel.Enable='off';
                app.ICSforNPanel.Enable='off';
                app.TICSforSpeedandDiffusionPanel.Enable='on';
                app.STICSforFlowMeasurementPanel.Enable='off';
            elseif strcmp(app.choiceAnalysis,'STICS for flow')
                app.FCSAnalysisPanel.Enable='off';
                app.ICSforNPanel.Enable='off';
                app.TICSforSpeedandDiffusionPanel.Enable='off';
                app.STICSforFlowMeasurementPanel.Enable='on';
            end
        end

        % Button pushed function: CalculateACFforFCSdataButton
        function CalculateACFforFCSdataButtonPushed(app, event)
            [app.corr,app.lags]=fcs_byfft(app.image_data,round(length(app.image_data)/100));
            app.lags
            app.corr
            app.UIAxes.YDir='normal';
            cla(app.UIAxes,'reset')
            cla(app.UIAxes3)
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            title(app.UIAxes,'')
            plot(app.UIAxes,app.lags,app.corr,'o')
            xlabel(app.UIAxes,'\tau (time points)','FontSize',16)
            ylabel(app.UIAxes,'<G(\tau)>','FontSize',16)
            title(app.UIAxes,'ACF for FCS experiment','FontSize',20)
            set(app.UIAxes,'XTick',10.^[1:log10(max(app.lags))],'YTick',[min(app.corr):(max(app.corr))/5:max(app.corr)]);
            set(app.UIAxes,'FontSize',20)
            xlim(app.UIAxes,[min(app.lags) max(app.lags)])
            ylim(app.UIAxes,[min(app.corr) 1.1*max(app.corr)])
            set(app.UIAxes,'XScale','log');
        end

        % Value changed function: tau_maxtofitEditField
        function tau_maxtofitEditFieldValueChanged(app, event)
            value = app.tau_maxtofitEditField.Value;
            app.taumax=value;
        end

        % Value changed function: FittingModelDropDown
        function FittingModelDropDownValueChanged(app, event)
            value = app.FittingModelDropDown.Value;
            app.choiceFCS=value;
            cla(app.UIAxes,'reset')
            cla(app.UIAxes3)
            semilogx(app.UIAxes,app.lags,app.corr,'o')
            xlabel(app.UIAxes,'\tau (time points)','FontSize',20)
            ylabel(app.UIAxes,'<G(\tau)>','FontSize',20)
             set(app.UIAxes,'XTick',10.^[1:log10(max(app.lags))],'YTick',[min(app.corr):(max(app.corr))/5:max(app.corr)]);
            set(app.UIAxes,'FontSize',20)
            xlim(app.UIAxes,[min(app.lags) max(app.lags)])
            ylim(app.UIAxes,[0 1.1*max(app.corr)])
            hold (app.UIAxes,'on')
            if app.taumax>length(app.corr)
              app.taumax=app.corr;
            end

            if strcmp(app.choiceFCS,'1 component Diffusion')
            diffFitting = difffit_fcsGUI(app.UIAxes,app.UIAxes3,app.lags(1:app.taumax)*app.timesize,app.corr(1:app.taumax),app.PSFSize,app.PSFZ);     
            elseif strcmp(app.choiceFCS,'1 component flow')
            flowFitting = flowfit_fcsGUI(app.UIAxes,app.UIAxes3,app.lags(1:app.taumax)*app.timesize,app.corr(1:app.taumax),app.PSFSize,app.PSFZ);
            elseif strcmp(app.choiceFCS,'Sum of 1 component diffusing and 1 component flowing')
            diffflowFitting= diffflowfit_fcsGUI(app.UIAxes,app.UIAxes3,app.lags(1:app.taumax)*app.timesize,app.corr(1:app.taumax),app.PSFSize,app.PSFZ);
            elseif strcmp(app.choiceFCS,'Single component 3D diffusion')
            Diff3dFitting = difffit3d_fcsGUI(app.UIAxes,app.UIAxes3,app.lags(1:app.taumax)*app.timesize,app.corr(1:app.taumax),app.PSFSize,app.PSFZ);
            end
        end

        % Value changed function: FitAverageCFDropDown
        function FitAverageCFDropDownValueChanged(app, event)
            value = app.FitAverageCFDropDown.Value;
            app.analyseAve=value;
            if strcmp(app.analyseAve,'no')
                app.analyseAve='n';
            elseif strcmp(app.analyseAve,'yes')
                app.analyseAve='y';
            end
        end

        % Value changed function: ExcludeG_00DropDown
        function ExcludeG_00DropDownValueChanged(app, event)
            value = app.ExcludeG_00DropDown.Value;
            app.removeG00=value;
            if strcmp(app.removeG00,'no')
                app.removeG00='n';
            elseif strcmp(app.removeG00,'yes')
                app.removeG00='y';
            end
        end

        % Value changed function: AnalyseImageEditField
        function AnalyseImageEditFieldValueChanged(app, event)
            value = app.AnalyseImageEditField.Value;
            app.im=value;
        end

        % Button pushed function: ApplyICSforrecoveryofNButton
        function ApplyICSforrecoveryofNButtonPushed(app, event)
            ICS2DCorr = corrfunc(app.image_data);
            app.ICS2DCorrCrop = autocrop(ICS2DCorr,20);
            app.UIAxes.YDir='normal';
            cla(app.UIAxes,'reset')
            cla(app.UIAxes3)
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            title(app.UIAxes,'')
            set(app.UIAxes,'XScale','linear');
            if strcmp(app.analyseAve,'n')
                app.a  = gaussfitoldGUI(app.UIAxes,app.ICS2DCorrCrop(:,:,app.im),'2d',app.pixelsize,app.removeG00);
                [X,Y] = meshgrid(-((size(app.ICS2DCorrCrop,2)-1)/2)*app.pixelsize:app.pixelsize:((size(app.ICS2DCorrCrop,2)-1)/2)*app.pixelsize,-((size(app.ICS2DCorrCrop,1)-1)/2)*app.pixelsize:app.pixelsize:(size(app.ICS2DCorrCrop,1)-1)/2*app.pixelsize);
                grid = [X Y];
                s=surf(app.UIAxes,X,Y,app.ICS2DCorrCrop(:,:,app.im));
                colormap(app.UIAxes,'jet')
                xlabel(app.UIAxes,'\eta (\mum)','FontSize',20)
                ylabel(app.UIAxes,'\xi (\mum)','FontSize',20)
                zlabel(app.UIAxes,'r(\xi,\eta)','FontSize',20)
                xlim(app.UIAxes,[min(X(:)) max(X(:))])
                ylim(app.UIAxes,[min(Y(:))  max(Y(:))])
                set(app.UIAxes,'FontSize',20)
                zlim(app.UIAxes,[-0.1  max(max(app.ICS2DCorrCrop(:,:,app.im)))])
                set(app.UIAxes,'XTick',[min(X(:)):max(X(:))/5:max(X(:))],'YTick',[min(Y(:)):max(Y(:))/5:max(Y(:))]);
                set(s,'LineStyle','none')
                title(app.UIAxes,'Average Cropped Spatial Autocorrelation Function')

            elseif strcmp(app.analyseAve,'y')
                aveCropCorr=mean(app.ICS2DCorrCrop,3);
                app.a  = gaussfitoldGUI(app.UIAxes,aveCropCorr,'2d',app.pixelsize,app.removeG00);
                [X,Y] = meshgrid(-((size(aveCropCorr,2)-1)/2)*app.pixelsize:app.pixelsize:((size(aveCropCorr,2)-1)/2)*app.pixelsize,-((size(aveCropCorr,1)-1)/2)*app.pixelsize:app.pixelsize:(size(aveCropCorr,1)-1)/2*app.pixelsize);
                grid = [X Y];
                s=surf(app.UIAxes,X,Y,aveCropCorr);
                colormap(app.UIAxes,'jet')
                xlabel(app.UIAxes,'\eta (\mum)','FontSize',13)
                ylabel(app.UIAxes,'\xi (\mum)','FontSize',13)
                zlabel(app.UIAxes,'r(\xi,\eta)','FontSize',13)
                xlim(app.UIAxes,[min(X(:)) max(X(:))])
                ylim(app.UIAxes,[min(Y(:))  max(Y(:))])
                set(app.UIAxes,'FontSize',20)
                zlim(app.UIAxes,[-0.1  max(max(app.ICS2DCorrCrop(:,:,app.im)))])
                set(s,'LineStyle','none')
                title(app.UIAxes,'Average Cropped Spatial Autocorrelation Function')
                
                
            end
        end

        % Button pushed function: ShowFitButton
        function ShowFitButtonPushed(app, event)
             [X,Y] = meshgrid(-((size(app.ICS2DCorrCrop,2)-1)/2)*app.pixelsize:app.pixelsize:((size(app.ICS2DCorrCrop,2)-1)/2)*app.pixelsize,-((size(app.ICS2DCorrCrop,1)-1)/2)*app.pixelsize:app.pixelsize:(size(app.ICS2DCorrCrop,1)-1)/2*app.pixelsize);
               set(app.UIAxes,'XScale','linear');
              if strcmp(app.analyseAve,'n')
                plotgaussfitForPubGUI(app.UIAxes,app.a(1,1:6),app.ICS2DCorrCrop(:,:,app.im),app.pixelsize,app.removeG00);
                title(app.UIAxes,'Fit of Average Cropped Spatial Autocorrelation Function')
                % results of fit
                particlesPerBeamArea = 1/app.a(1,1);
                beamArea = pi*app.a(:,2)*app.a(:,3);
                density = particlesPerBeamArea/beamArea;
                set(app.UIAxes,'XLim',[min(X(:)) max(X(:))])
                set(app.UIAxes,'YLim',[min(Y(:))  max(Y(:))])
                set(app.UIAxes,'FontSize',20)
                set(app.UIAxes,'ZLim',[-0.1  max(max(app.ICS2DCorrCrop(:,:,app.im)))])
                zlim=get(app.UIAxes,'ZLim');
                xlim=get(app.UIAxes,'XLim');
                ylim=get(app.UIAxes,'YLim');
                text(app.UIAxes,xlim(2),ylim(2),0.9*zlim(2),['Beam Area =' num2str(round(beamArea,2,'significant')) ' \mum^{2}'],'Color',[1 0 0],'FontSize',20)
                text(app.UIAxes,xlim(2),ylim(2),0.8*zlim(2),['Particles Per Beam Area =' num2str(round(particlesPerBeamArea,2,'significant'))],'Color',[1 0 0],'FontSize',20)
                text(app.UIAxes,xlim(2),ylim(2),0.7*zlim(2),['Particles Density =' num2str(round(density,2,'significant')) ' \mum^{-2}'],'Color',[1 0 0],'FontSize',20)
             elseif strcmp(app.analyseAve,'y')
                 aveCropCorr=mean(app.ICS2DCorrCrop,3);
                plotgaussfitForPubGUI(app.UIAxes,app.a(1,1:6),aveCropCorr,app.pixelsize,app.removeG00);
                title(app.UIAxes,'Fit of Average Cropped Spatial Autocorrelation Function')
                % results of fit
                particlesPerBeamArea = 1/app.a(1,1);
                beamArea = pi*app.a(:,2)*app.a(:,3);
                density = particlesPerBeamArea/beamArea;
                set(app.UIAxes,'XLim',[min(X(:)) max(X(:))])
                set(app.UIAxes,'YLim',[min(Y(:))  max(Y(:))])
                set(app.UIAxes,'FontSize',20)
                set(app.UIAxes,'ZLim',[-0.1  max(max(app.ICS2DCorrCrop(:,:,app.im)))])
                zlim=get(app.UIAxes,'ZLim');
                xlim=get(app.UIAxes,'XLim');
                ylim=get(app.UIAxes,'YLim');
                text(app.UIAxes,xlim(2),ylim(2),0.9*zlim(2),['Beam Area =' num2str(round(beamArea,2,'significant')) ' \mum^{2}'],'Color',[1 0 0],'FontSize',20)
                text(app.UIAxes,xlim(2),ylim(2),0.8*zlim(2),['Particles Per Beam Area =' num2str(round(particlesPerBeamArea,2,'significant'))],'Color',[1 0 0],'FontSize',20)
                text(app.UIAxes,xlim(2),ylim(2),0.7*zlim(2),['Particles Density =' num2str(round(density,2,'significant')) ' \mum^{-2}'],'Color',[1 0 0],'FontSize',20)
              end
        end

        % Button pushed function: ApplyTICSforvandDButton
        function ApplyTICSforvandDButtonPushed(app, event)
            GtDiff = ticsGUI(app.UIAxes2,app.image_data,1);
            app.lags=GtDiff(:,1);
            app.corr=GtDiff(:,2);
            app.UIAxes.YDir='normal';
            cla(app.UIAxes,'reset');
            cla(app.UIAxes3)
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            zlabel(app.UIAxes,'')
            title(app.UIAxes,'')
            plot(app.UIAxes,GtDiff(:,1),GtDiff(:,2),'o')
            set(app.UIAxes,'XScale','log');
            xlabel(app.UIAxes,'\tau (frames)','FontSize',20)
            ylabel(app.UIAxes,'<r_{11}(0,0,\tau)>','FontSize',20)
            set(app.UIAxes,'XLim',[min(GtDiff(:,1)) max(GtDiff(:,1))])
            set(app.UIAxes,'YLim',[min(GtDiff(:,2))  max(GtDiff(:,2))])
            set(app.UIAxes,'ZLim',[0 10^-12])
            set(app.UIAxes,'FontSize',20)
        end

        % Value changed function: tau_maxtofitEditField_3
        function tau_maxtofitEditField_3ValueChanged(app, event)
            value = app.tau_maxtofitEditField_3.Value;
            app.taumax=value;
        end

        % Value changed function: FittingModelDropDown_2
        function FittingModelDropDown_2ValueChanged(app, event)
            value = app.FittingModelDropDown_2.Value;
            choiceTICS=value;
            cla(app.UIAxes,'reset')
            cla(app.UIAxes3)
            semilogx(app.UIAxes,app.lags,app.corr,'o')
            xlabel(app.UIAxes,'\tau (time points)','FontSize',20)
            ylabel(app.UIAxes,'<G(\tau)>','FontSize',20)
             set(app.UIAxes,'XTick',10.^[1:log10(max(app.lags))],'YTick',[min(app.corr):(max(app.corr))/5:max(app.corr)]);
            set(app.UIAxes,'FontSize',20)
            xlim(app.UIAxes,[min(app.lags) max(app.lags)])
            ylim(app.UIAxes,[0 1.1*max(app.corr)])
             hold (app.UIAxes,'on')
            if app.taumax>length(app.corr)
              app.taumax=app.corr;
            end

            if strcmp(choiceTICS,'1 component Diffusion')
            diffFitting = difffitGUI(app.UIAxes,app.UIAxes3,app.lags(1:app.taumax)*app.timesize,app.corr(1:app.taumax),app.PSFSize);     
            elseif strcmp(choiceTICS,'1 component flow')
            flowFitting = flowfitGUI(app.UIAxes,app.UIAxes3,app.lags(1:app.taumax)*app.timesize,app.corr(1:app.taumax),app.PSFSize);
            elseif strcmp(choiceTICS,'Sum of 1 component diffusing and 1 component flowing')
            diffflowFitting= diffflowfitGUI(app.UIAxes,app.UIAxes3,app.lags(1:app.taumax)*app.timesize,app.corr(1:app.taumax),app.PSFSize);
            elseif strcmp(choiceTICS,'Single component 3D diffusion')
            Diff3dFitting = difffit3dGUI(app.UIAxes,app.UIAxes3,app.lags(1:app.taumax)*app.timesize,app.corr(1:app.taumax),app.PSFSize,app.PSFZ);
            end
        end

        % Value changed function: ImmobileFilteringDropDown
        function ImmobileFilteringDropDownValueChanged(app, event)
            value = app.ImmobileFilteringDropDown.Value;
            app.filtering=value;
            if strcmp(app.filtering,'yes')
                app.image_data=immfilterGUI(app.UIAxes2,double(app.image_data));
            end
        end

        % Value changed function: tau_maxtofitEditField_2
        function tau_maxtofitEditField_2ValueChanged(app, event)
            value = app.tau_maxtofitEditField_2.Value;
            app.taumax=value;
            
        end

        % Button pushed function: ApplySTICSforflowrecoveryButton
        function ApplySTICSforflowrecoveryButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            cla(app.UIAxes3)
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            zlabel(app.UIAxes,'')
            title(app.UIAxes,'') 
            app.FrameSlider.Value=1;
            app.sticscorr=stics_byfft(app.image_data,mean(app.image_data(:)),app.taumax);
            app.sticscorr = autocrop(app.sticscorr,50);
            [X,Y] = meshgrid(-((size(app.sticscorr,2)-1)/2)*app.pixelsize:app.pixelsize:((size(app.sticscorr,2)-1)/2)*app.pixelsize,-((size(app.sticscorr,1)-1)/2)*app.pixelsize:app.pixelsize:(size(app.sticscorr,1)-1)/2*app.pixelsize);
            app.FrameSlider.Limits=[1 size(app.sticscorr,3)];
            app.UIAxes.YDir='reverse';
            xlabel(app.UIAxes,'\eta (\mum)','FontSize',20)
            ylabel(app.UIAxes,'\xi (\mum)','FontSize',20)
            colormap(app.UIAxes,'pink')
            imagesc(app.UIAxes,X(1,:),Y(:,1),app.sticscorr(:,:,1))
            set(app.UIAxes,'XLim',[min(X(:)) max(X(:))])
            set(app.UIAxes,'YLim',[min(Y(:))  max(Y(:))])
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            set(app.UIAxes,'XScale','linear');
        end

        % Button pushed function: ShowFlowPlotButton
        function ShowFlowPlotButtonPushed(app, event)
            app.UIAxes.YDir='normal';
            cla(app.UIAxes,'reset');
            cla(app.UIAxes3)
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            zlabel(app.UIAxes,'')
            title(app.UIAxes,'') 
            [Vx,Vy] = velocityGUI(app.UIAxes,app.image_data,app.timesize,app.pixelsize,app.filtering,app.taumax,'n');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.SimulationParametersPanel = uipanel(app.UIFigure);
            app.SimulationParametersPanel.Title = '2. Simulation Parameters';
            app.ImagesizeinxpixelsEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.ImagesizeinxpixelsEditFieldLabel.Interpreter = 'tex';
            app.ImagesizeinxpixelsEditFieldLabel.Text = 'Image size in x (pixels)';
            app.ImagesizeinxpixelsEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.ImagesizeinxpixelsEditField.ValueChangedFcn = createCallbackFcn(app, @ImagesizeinxpixelsEditFieldValueChanged, true);
            app.ImagesizeinxpixelsEditField.Value = 256;
            app.ImagesizeinypixelsEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.ImagesizeinypixelsEditFieldLabel.Interpreter = 'tex';
            app.ImagesizeinypixelsEditFieldLabel.Text = 'Image size in y (pixels)';
            app.ImagesizeinypixelsEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.ImagesizeinypixelsEditField.ValueChangedFcn = createCallbackFcn(app, @ImagesizeinypixelsEditFieldValueChanged, true);
            app.ImagesizeinypixelsEditField.Value = 256;
            app.ofimagesinseriesEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.ofimagesinseriesEditFieldLabel.Interpreter = 'tex';
            app.ofimagesinseriesEditFieldLabel.Text = '# of images in series ';
            app.ofimagesinseriesEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.ofimagesinseriesEditField.ValueChangedFcn = createCallbackFcn(app, @ofimagesinseriesEditFieldValueChanged, true);
            app.ofimagesinseriesEditField.Value = 100;
            app.PixelsizeummLabel = uilabel(app.SimulationParametersPanel);
            app.PixelsizeummLabel.Interpreter = 'tex';
            app.PixelsizeummLabel.Text = 'Pixel Size (\mum)';
            app.PixelSizemumEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.PixelSizemumEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizemumEditFieldValueChanged, true);
            app.PixelSizemumEditField.Value = 0.1;
            app.FrameTimesEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.FrameTimesEditFieldLabel.Interpreter = 'tex';
            app.FrameTimesEditFieldLabel.Text = 'Frame Time (s)';
            app.FrameTimesEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.FrameTimesEditField.ValueChangedFcn = createCallbackFcn(app, @FrameTimesEditFieldValueChanged, true);
            app.FrameTimesEditField.Value = 1;
            app.PSFtypeDropDownLabel = uilabel(app.SimulationParametersPanel);
            app.PSFtypeDropDownLabel.Interpreter = 'tex';
            app.PSFtypeDropDownLabel.Text = 'PSF type ';
            app.PSFtypeDropDown = uidropdown(app.SimulationParametersPanel);
            app.PSFtypeDropDown.Items = {'Gaussian', 'Airy'};
            app.PSFtypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PSFtypeDropDownOpening, true);
            app.PSFtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PSFtypeDropDownValueChanged, true);
            app.PSFtypeDropDown.Value = 'Gaussian';
            app.PSFLateralRadiusmumEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.PSFLateralRadiusmumEditFieldLabel.Interpreter = 'tex';
            app.PSFLateralRadiusmumEditFieldLabel.Text = 'PSF Lateral Radius (\mum)';
            app.PSFLateralRadiusmumEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.PSFLateralRadiusmumEditField.ValueChangedFcn = createCallbackFcn(app, @PSFLateralRadiusmumEditFieldValueChanged, true);
            app.PSFLateralRadiusmumEditField.Value = 0.4;
            app.PSFAxialRadiusmumEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.PSFAxialRadiusmumEditFieldLabel.Interpreter = 'tex';
            app.PSFAxialRadiusmumEditFieldLabel.Text = 'PSF Axial Radius (\mum)';
            app.PSFAxialRadiusmumEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.PSFAxialRadiusmumEditField.ValueChangedFcn = createCallbackFcn(app, @PSFAxialRadiusmumEditFieldValueChanged, true);
            app.ImagesBitDepthDropDownLabel = uilabel(app.SimulationParametersPanel);
            app.ImagesBitDepthDropDownLabel.Interpreter = 'tex';
            app.ImagesBitDepthDropDownLabel.Text = 'Images Bit Depth';
            app.ImagesBitDepthDropDown = uidropdown(app.SimulationParametersPanel);
            app.ImagesBitDepthDropDown.Items = {'8', '12', '16'};
            app.ImagesBitDepthDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ImagesBitDepthDropDownOpening, true);
            app.ImagesBitDepthDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagesBitDepthDropDownValueChanged, true);
            app.ImagesBitDepthDropDown.Value = '12';
            app.DetectorCountingNoise1to20Label = uilabel(app.SimulationParametersPanel);
            app.DetectorCountingNoise1to20Label.Interpreter = 'tex';
            app.DetectorCountingNoise1to20Label.Text = 'Detector Counting Noise (1 to 20)';
            app.DetectorCountingNoise1to20EditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.DetectorCountingNoise1to20EditField.ValueChangedFcn = createCallbackFcn(app, @DetectorCountingNoise1to20EditFieldValueChanged, true);
            app.GaussianBackgroundNoise0to1EditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.GaussianBackgroundNoise0to1EditFieldLabel.Interpreter = 'tex';
            app.GaussianBackgroundNoise0to1EditFieldLabel.Text = 'Gaussian Background Noise (0 to 1)';
            app.GaussianBackgroundNoise0to1EditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.GaussianBackgroundNoise0to1EditField.ValueChangedFcn = createCallbackFcn(app, @GaussianBackgroundNoise0to1EditFieldValueChanged, true);
            app.BleachingAndBlinkingBehaviourDropDownLabel = uilabel(app.SimulationParametersPanel);
            app.BleachingAndBlinkingBehaviourDropDownLabel.Text = 'Bleaching  And Blinking Behaviour';
            app.BleachingAndBlinkingBehaviourDropDown = uidropdown(app.SimulationParametersPanel);
            app.BleachingAndBlinkingBehaviourDropDown.Items = {'none', 'mono', 'qd'};
            app.BleachingAndBlinkingBehaviourDropDown.DropDownOpeningFcn = createCallbackFcn(app, @BleachingAndBlinkingBehaviourDropDownOpening, true);
            app.BleachingAndBlinkingBehaviourDropDown.ValueChangedFcn = createCallbackFcn(app, @BleachingAndBlinkingBehaviourDropDownValueChanged, true);
            app.BleachingAndBlinkingBehaviourDropDown.Value = 'none';
            app.DoFCSExperimentDropDownLabel = uilabel(app.SimulationParametersPanel);
            app.DoFCSExperimentDropDownLabel.Text = 'Do FCS Experiment?';
            app.DoFCSExperimentDropDown = uidropdown(app.SimulationParametersPanel);
            app.DoFCSExperimentDropDown.Items = {'no', 'yes'};
            app.DoFCSExperimentDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DoFCSExperimentDropDownOpening, true);
            app.DoFCSExperimentDropDown.ValueChangedFcn = createCallbackFcn(app, @DoFCSExperimentDropDownValueChanged, true);
            app.DoFCSExperimentDropDown.Value = 'no';
            app.MaskSizeToCreateCellArea0to1EditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.MaskSizeToCreateCellArea0to1EditFieldLabel.Text = 'Mask Size To Create Cell Area (0 to 1)';
            app.MaskSizeToCreateCellArea0to1EditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.MaskSizeToCreateCellArea0to1EditField.ValueChangedFcn = createCallbackFcn(app, @MaskSizeToCreateCellArea0to1EditFieldValueChanged, true);
            app.ParticlesPopulationsSpecificationsPanel = uipanel(app.UIFigure);
            app.ParticlesPopulationsSpecificationsPanel.Title = '3. Particles Populations Specifications';
            app.ParticleDencitypermum2EditFieldLabel_2 = uilabel(app.ParticlesPopulationsSpecificationsPanel);
            app.ParticleDencitypermum2EditFieldLabel_2.Interpreter = 'tex';
            app.ParticleDencitypermum2EditFieldLabel_2.Text = 'Particle Density (per \mum^{2})';
            app.EditField = uieditfield(app.ParticlesPopulationsSpecificationsPanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = '1';
            app.ParticleDencitypermum2EditFieldLabel_3 = uilabel(app.ParticlesPopulationsSpecificationsPanel);
            app.ParticleDencitypermum2EditFieldLabel_3.Interpreter = 'tex';
            app.ParticleDencitypermum2EditFieldLabel_3.Text = 'Bleaching Decay Constant ';
            app.EditField_2 = uieditfield(app.ParticlesPopulationsSpecificationsPanel, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = '0';
            app.ParticleDencitypermum2EditFieldLabel_4 = uilabel(app.ParticlesPopulationsSpecificationsPanel);
            app.ParticleDencitypermum2EditFieldLabel_4.Interpreter = 'tex';
            app.ParticleDencitypermum2EditFieldLabel_4.Text = 'Quantum Yield of Dye';
            app.EditField_3 = uieditfield(app.ParticlesPopulationsSpecificationsPanel, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = '1 ';
            app.ParticleDencitypermum2EditFieldLabel_5 = uilabel(app.ParticlesPopulationsSpecificationsPanel);
            app.ParticleDencitypermum2EditFieldLabel_5.Interpreter = 'tex';
            app.ParticleDencitypermum2EditFieldLabel_5.Text = 'Diffusion Coefficient (\mum^{2}s^{-1})';
            app.EditField_4 = uieditfield(app.ParticlesPopulationsSpecificationsPanel, 'text');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Value = '0';
            app.ParticleDencitypermum2EditFieldLabel_6 = uilabel(app.ParticlesPopulationsSpecificationsPanel);
            app.ParticleDencitypermum2EditFieldLabel_6.Interpreter = 'tex';
            app.ParticleDencitypermum2EditFieldLabel_6.Text = 'Flow in X (\mums^{-1})';
            app.EditField_5 = uieditfield(app.ParticlesPopulationsSpecificationsPanel, 'text');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Value = '0';
            app.ParticleDencitypermum2EditFieldLabel_7 = uilabel(app.ParticlesPopulationsSpecificationsPanel);
            app.ParticleDencitypermum2EditFieldLabel_7.Interpreter = 'tex';
            app.ParticleDencitypermum2EditFieldLabel_7.Text = 'Flow in Y (\mums^{-1})';
            app.EditField_6 = uieditfield(app.ParticlesPopulationsSpecificationsPanel, 'text');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Value = '0';
            app.ParticleDencitypermum2EditFieldLabel_8 = uilabel(app.ParticlesPopulationsSpecificationsPanel);
            app.ParticleDencitypermum2EditFieldLabel_8.Interpreter = 'tex';
            app.ParticleDencitypermum2EditFieldLabel_8.Text = 'Flow in Z (\mums^{-1})';
            app.EditField_7 = uieditfield(app.ParticlesPopulationsSpecificationsPanel, 'text');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_7.Value = '0';
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FCSAnalysisPanel = uipanel(app.UIFigure);
            app.FCSAnalysisPanel.Title = 'FCS Analysis Panel';
            app.tau_maxtofitEditFieldLabel = uilabel(app.FCSAnalysisPanel);
            app.tau_maxtofitEditFieldLabel.Interpreter = 'tex';
            app.tau_maxtofitEditFieldLabel.Text = '\tau_{max} to fit';
            app.tau_maxtofitEditField = uieditfield(app.FCSAnalysisPanel, 'numeric');
            app.tau_maxtofitEditField.ValueChangedFcn = createCallbackFcn(app, @tau_maxtofitEditFieldValueChanged, true);
            app.tau_maxtofitEditField.Value = 20;
            app.CalculateACFforFCSdataButton = uibutton(app.FCSAnalysisPanel, 'push');
            app.CalculateACFforFCSdataButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateACFforFCSdataButtonPushed, true);
            app.CalculateACFforFCSdataButton.Text = 'Calculate ACF for FCS data';
            app.FittingModelDropDownLabel = uilabel(app.FCSAnalysisPanel);
            app.FittingModelDropDownLabel.Text = 'Fitting Model ';
            app.FittingModelDropDown = uidropdown(app.FCSAnalysisPanel);
            app.FittingModelDropDown.Items = {'1 component Diffusion', '1 component flow', 'Sum of 1 component diffusing and 1 component flowing', 'Single component 3D diffusion'};
            app.FittingModelDropDown.ValueChangedFcn = createCallbackFcn(app, @FittingModelDropDownValueChanged, true);
            app.FittingModelDropDown.Value = '1 component Diffusion';
            app.STICSforFlowMeasurementPanel = uipanel(app.UIFigure);
            app.STICSforFlowMeasurementPanel.Title = 'STICS for Flow Measurement';
            app.tau_maxtofitEditField_2Label = uilabel(app.STICSforFlowMeasurementPanel);
            app.tau_maxtofitEditField_2Label.Interpreter = 'tex';
            app.tau_maxtofitEditField_2Label.Text = '\tau_{max} to fit';
            app.tau_maxtofitEditField_2 = uieditfield(app.STICSforFlowMeasurementPanel, 'numeric');
            app.tau_maxtofitEditField_2.ValueChangedFcn = createCallbackFcn(app, @tau_maxtofitEditField_2ValueChanged, true);
            app.tau_maxtofitEditField_2.Value = 20;
            app.ImmobileFilteringDropDownLabel = uilabel(app.STICSforFlowMeasurementPanel);
            app.ImmobileFilteringDropDownLabel.Interpreter = 'tex';
            app.ImmobileFilteringDropDownLabel.Text = 'Immobile Filtering';
            app.ImmobileFilteringDropDown = uidropdown(app.STICSforFlowMeasurementPanel);
            app.ImmobileFilteringDropDown.Items = {'no', 'yes'};
            app.ImmobileFilteringDropDown.ValueChangedFcn = createCallbackFcn(app, @ImmobileFilteringDropDownValueChanged, true);
            app.ImmobileFilteringDropDown.Value = 'no';
            app.ApplySTICSforflowrecoveryButton = uibutton(app.STICSforFlowMeasurementPanel, 'push');
            app.ApplySTICSforflowrecoveryButton.ButtonPushedFcn = createCallbackFcn(app, @ApplySTICSforflowrecoveryButtonPushed, true);
            app.ApplySTICSforflowrecoveryButton.Text = 'Apply STICS for flow recovery';
            app.ShowFlowPlotButton = uibutton(app.STICSforFlowMeasurementPanel, 'push');
            app.ShowFlowPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ShowFlowPlotButtonPushed, true);
            app.ShowFlowPlotButton.Text = 'Show Flow Plot';
            app.ChoiceofFFCSAnalysisButtonGroup = uibuttongroup(app.UIFigure);
            app.ChoiceofFFCSAnalysisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChoiceofFFCSAnalysisButtonGroupSelectionChanged, true);
            app.ChoiceofFFCSAnalysisButtonGroup.Title = '5. Choice of FFCS Analysis';
            app.FCSButton = uiradiobutton(app.ChoiceofFFCSAnalysisButtonGroup);
            app.FCSButton.Text = 'FCS';
            app.FCSButton.Value = true;
            app.ICSforNButton = uiradiobutton(app.ChoiceofFFCSAnalysisButtonGroup);
            app.ICSforNButton.Text = 'ICS for <N>';
            app.STICSforflowButton = uiradiobutton(app.ChoiceofFFCSAnalysisButtonGroup);
            app.STICSforflowButton.Text = 'STICS for flow';
            app.TICSforspeedanddiffusionButton = uiradiobutton(app.ChoiceofFFCSAnalysisButtonGroup);
            app.TICSforspeedanddiffusionButton.Text = 'TICS for speed and diffusion';
            app.ICSforNPanel = uipanel(app.UIFigure);
            app.ICSforNPanel.Title = 'ICS for <N>';
            app.AnalyseImageLabel = uilabel(app.ICSforNPanel);
            app.AnalyseImageLabel.Interpreter = 'tex';
            app.AnalyseImageLabel.Text = 'Analyse Image';
            app.AnalyseImageEditField = uieditfield(app.ICSforNPanel, 'numeric');
            app.AnalyseImageEditField.ValueChangedFcn = createCallbackFcn(app, @AnalyseImageEditFieldValueChanged, true);
            app.AnalyseImageEditField.Value = 1;
            app.FitAverageCFDropDownLabel = uilabel(app.ICSforNPanel);
            app.FitAverageCFDropDownLabel.Interpreter = 'tex';
            app.FitAverageCFDropDownLabel.Text = 'Fit Average CF';
            app.FitAverageCFDropDown = uidropdown(app.ICSforNPanel);
            app.FitAverageCFDropDown.Items = {'no', 'yes'};
            app.FitAverageCFDropDown.ValueChangedFcn = createCallbackFcn(app, @FitAverageCFDropDownValueChanged, true);
            app.FitAverageCFDropDown.Value = 'no';
            app.ExcludeG_00fromfitLabel = uilabel(app.ICSforNPanel);
            app.ExcludeG_00fromfitLabel.Interpreter = 'tex';
            app.ExcludeG_00fromfitLabel.Text = 'Exclude G_{0,0}';
            app.ExcludeG_00DropDown = uidropdown(app.ICSforNPanel);
            app.ExcludeG_00DropDown.Items = {'no', 'yes'};
            app.ExcludeG_00DropDown.ValueChangedFcn = createCallbackFcn(app, @ExcludeG_00DropDownValueChanged, true);
            app.ExcludeG_00DropDown.Value = 'no';
            app.ApplyICSforrecoveryofNButton = uibutton(app.ICSforNPanel, 'push');
            app.ApplyICSforrecoveryofNButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyICSforrecoveryofNButtonPushed, true);
            app.ApplyICSforrecoveryofNButton.Text = 'Apply ICS for recovery of <N>';
            app.ShowFitButton = uibutton(app.ICSforNPanel, 'push');
            app.ShowFitButton.ButtonPushedFcn = createCallbackFcn(app, @ShowFitButtonPushed, true);
            app.ShowFitButton.Text = 'Show Fit';
            app.TICSforSpeedandDiffusionPanel = uipanel(app.UIFigure);
            app.TICSforSpeedandDiffusionPanel.Title = 'TICS for Speed and Diffusion';
            app.FittingModelDropDownLabel_2 = uilabel(app.TICSforSpeedandDiffusionPanel);
            app.FittingModelDropDownLabel_2.Text = 'Fitting Model ';
            app.tau_maxtofitEditField_3Label = uilabel(app.TICSforSpeedandDiffusionPanel);
            app.tau_maxtofitEditField_3Label.Interpreter = 'tex';
            app.tau_maxtofitEditField_3Label.Text = '\tau_{max} to fit';
            app.tau_maxtofitEditField_3 = uieditfield(app.TICSforSpeedandDiffusionPanel, 'numeric');
            app.tau_maxtofitEditField_3.ValueChangedFcn = createCallbackFcn(app, @tau_maxtofitEditField_3ValueChanged, true);
            app.tau_maxtofitEditField_3.Value = 20;
            app.FittingModelDropDown_2 = uidropdown(app.TICSforSpeedandDiffusionPanel);
            app.FittingModelDropDown_2.Items = {'1 component Diffusion', '1 component flow', 'Sum of 1 component diffusing and 1 component flowing', 'Single component 3D diffusion'};
            app.FittingModelDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FittingModelDropDown_2ValueChanged, true);
            app.FittingModelDropDown_2.Value = '1 component Diffusion';
            app.ApplyTICSforvandDButton = uibutton(app.TICSforSpeedandDiffusionPanel, 'push');
            app.ApplyTICSforvandDButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyTICSforvandDButtonPushed, true);
            app.ApplyTICSforvandDButton.Text = 'Apply TICS for |v| and D ';
            app.InitialiseParametersButton = uibutton(app.UIFigure, 'push');
            app.InitialiseParametersButton.ButtonPushedFcn = createCallbackFcn(app, @InitialiseParametersButtonPushed, true);
            app.InitialiseParametersButton.Text = '1.Initialise Parameters';
            app.SimulateFFCSDataNowButton = uibutton(app.UIFigure, 'push');
            app.SimulateFFCSDataNowButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateFFCSDataNowButtonPushed, true);
            app.SimulateFFCSDataNowButton.Text = '4. Simulate FFCS Data Now !';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFCSguide_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end